classdef ClassifierGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        HIGHLIGHTButton            matlab.ui.control.Button
        SelectwhichalbumyouarelookingforinthedropdownLabel_2  matlab.ui.control.Label
        SelectwhichalbumyouarelookingforinthedropdownLabel  matlab.ui.control.Label
        Inputapicturewith1ormorealbumspresentLabel  matlab.ui.control.Label
        HowtouseLabel              matlab.ui.control.Label
        InteractiveVinylWallLabel  matlab.ui.control.Label
        LOOKFORDropDown            matlab.ui.control.DropDown
        LOOKFORDropDownLabel       matlab.ui.control.Label
        TAKEPICTUREButton          matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        Result                     matlab.ui.control.UIAxes
        Input                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TAKEPICTUREButton
        function createPicture(app, event)
             
            % Take an image from the webcam
             x = getPictureFromWebcam();
             imwrite(x, 'input', 'png');

             % Change the input picture to pgm
             changeImageFormat('input', 'input.pgm');
             a = imread('input.pgm');
             
             % Show to result to the GUI
             app.Input.Visible = 'on';
             imshow(a, 'parent', app.Input); 
        end

        % Button pushed function: HIGHLIGHTButton
        function matchAlbum(app, event)
           dropdown = (append(append('data/coverDataTest/', app.LOOKFORDropDown.Value), '.pgm'));
          

            % Mockdata untill image extraction is done.
            mockData = ["data/mockData/arcticmonkeys.pgm", "data/mockData/beatles.pgm", "data/mockData/bowie.pgm", "data/mockData/elo.pgm", "data/mockData/elton.pgm", "data/mockData/nirvana.pgm"];
           
            
            [bestMatch, certainty] = determineBestMatch(mockData, dropdown);

            if (certainty == Certainty.Low) 
                disp("There is probably no mathc in this image")
                
            elseif (certainty == Certainty.Medium)
                disp("Probably that there is a match here")
                app.Result.Visible = 'on';
                imshow(bestMatch, 'parent', app.Result); 
            else 
                disp("Pretty sure there is a match")
                app.Result.Visible = 'on';
                imshow(bestMatch, 'parent', app.Result); 
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TAKEPICTUREButton = uibutton(app.LeftPanel, 'push');
            app.TAKEPICTUREButton.ButtonPushedFcn = createCallbackFcn(app, @createPicture, true);
            app.TAKEPICTUREButton.Text = 'TAKE PICTURE';
            app.LOOKFORDropDownLabel = uilabel(app.LeftPanel);
            app.LOOKFORDropDownLabel.Text = 'LOOK FOR';
            app.LOOKFORDropDown = uidropdown(app.LeftPanel);
            app.LOOKFORDropDown.Items = {'alexturner', 'killers', 'arcticmonkeys', 'elton', 'lana', 'beatles', 'froukje', 'nirvana', 'beatlesmystery', 'georgemichael', 'pinkfloyd', 'boney', 'gunsnroses', 'rhcp', 'bowie', 'harry', 'coldplay'};
            app.LOOKFORDropDown.Value = 'coldplay';
            app.InteractiveVinylWallLabel = uilabel(app.LeftPanel);
            app.InteractiveVinylWallLabel.Text = 'Interactive Vinyl Wall';
            app.HowtouseLabel = uilabel(app.LeftPanel);
            app.HowtouseLabel.Text = 'How to use:';
            app.Inputapicturewith1ormorealbumspresentLabel = uilabel(app.LeftPanel);
            app.Inputapicturewith1ormorealbumspresentLabel.Text = '1. Input a picture with 1 or more albums present';
            app.SelectwhichalbumyouarelookingforinthedropdownLabel = uilabel(app.LeftPanel);
            app.SelectwhichalbumyouarelookingforinthedropdownLabel.Text = '2. Select which album you are looking for in the drop down';
            app.SelectwhichalbumyouarelookingforinthedropdownLabel_2 = uilabel(app.LeftPanel);
            app.SelectwhichalbumyouarelookingforinthedropdownLabel_2.Text = '3. Select highlight to let the program generate the output input with the matching image highlighted';
            app.HIGHLIGHTButton = uibutton(app.LeftPanel, 'push');
            app.HIGHLIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @matchAlbum, true);
            app.HIGHLIGHTButton.Text = 'HIGHLIGHT';
            app.RightPanel = uipanel(app.GridLayout);
            app.Input = uiaxes(app.RightPanel);
            title(app.Input, 'Input')
            zlabel(app.Input, 'Z')
            app.Input.Visible = 'off';
            app.Result = uiaxes(app.RightPanel);
            title(app.Result, 'Output')
            zlabel(app.Result, 'Z')
            app.Result.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassifierGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end